classdef Gesture_Recognition_App_WithMaths < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SandboxModeTab              matlab.ui.container.Tab
        CONTINUOUSAPPLYButton       matlab.ui.control.Button
        DataAugmentationsNOTWEBCAMButtonGroup  matlab.ui.container.ButtonGroup
        NoiseInjectionButton        matlab.ui.control.RadioButton
        RotateButton                matlab.ui.control.RadioButton
        NoAugmentationsButton       matlab.ui.control.RadioButton
        thumbgesture                matlab.ui.control.Image
        rockgesture                 matlab.ui.control.Image
        CLOSECAMButton              matlab.ui.control.Button
        ModelStatusLabel            matlab.ui.control.Label
        fivegesture                 matlab.ui.control.Image
        fourgesture                 matlab.ui.control.Image
        threegesture                matlab.ui.control.Image
        onegesture                  matlab.ui.control.Image
        twogesture                  matlab.ui.control.Image
        PleaseLoadModelLabel        matlab.ui.control.Label
        PredictedLabel              matlab.ui.control.Label
        ClassifyButton              matlab.ui.control.Button
        MakeyourpickPanel           matlab.ui.container.Panel
        WebcamInputLabel            matlab.ui.control.Label
        OpenCamButton               matlab.ui.control.Button
        CustomImageLabel            matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        SelectModelDropDown         matlab.ui.control.DropDown
        SelectModelDropDownLabel    matlab.ui.control.Label
        ImageOptionsDropDown        matlab.ui.control.DropDown
        ImageOptionsDropDownLabel   matlab.ui.control.Label
        SandboxModeLabel            matlab.ui.control.Label
        GestureRecognitionLabel     matlab.ui.control.Label
        ConfidenceAxis              matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        MathematicsGameTab          matlab.ui.container.Tab
        ClickEnterLabel             matlab.ui.control.Label
        ChooseOperator              matlab.ui.control.Label
        NoButton                    matlab.ui.control.Button
        YesButton                   matlab.ui.control.Button
        numGuessLabel               matlab.ui.control.Label
        DidyoumeanLabel             matlab.ui.control.Label
        CalculatorPanel             matlab.ui.container.Panel
        CurrentCalculationLabel     matlab.ui.control.Label
        EnterButton                 matlab.ui.control.Button
        PreviousCalculationLabel    matlab.ui.control.Label
        PreviousMathsLabel          matlab.ui.control.Label
        CurrentMathsLabel           matlab.ui.control.Label
        ClearButton                 matlab.ui.control.Button
        DivideButton                matlab.ui.control.Button
        MultiplyButton              matlab.ui.control.Button
        MinusButton                 matlab.ui.control.Button
        PlusButton                  matlab.ui.control.Button
        TakeSnapshotButton          matlab.ui.control.Button
        MakeyourpickPanel_2         matlab.ui.container.Panel
        SelectModelDropDown_2       matlab.ui.control.DropDown
        SelectModelDropDown_2Label  matlab.ui.control.Label
        MathsImage                  matlab.ui.control.Image
        ModelStatusLabel_2          matlab.ui.control.Label
        PleaseLoadModelLabel_2      matlab.ui.control.Label
        MathsGameModeLabel          matlab.ui.control.Label
        GestureRecognitionLabel_2   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        original_img
        Webcam_Status=true
        model
        Prediction
        scores
        selectedbutton
        switchstatus
        constantapply=false;
        %for maths tab:
        mathsTimer;
        cam=webcam(1);
        newNum;
    end
    
    methods (Access = private)
        
        function axisreset(app)
            cla(app.UIAxes,'reset')
            app.UIAxes.XLabel.String=' ';
            app.UIAxes.YLabel.String=' ';
            app.UIAxes.XTickLabel=' ';
            app.UIAxes.YTickLabel='es ';
            app.onegesture.Visible='off';
            app.twogesture.Visible='off';
            app.threegesture.Visible='off';
            app.fourgesture.Visible='off';
            app.fivegesture.Visible='off';
            app.rockgesture.Visible='off';
            app.thumbgesture.Visible='off'
            app.PredictedLabel.Visible='off';
            app.CLOSECAMButton.Visible='off'
            app.CONTINUOUSAPPLYButton.Visible='off'
            % app.SobelXLamp.Color='r';
            % app.SobelYLamp.Color='r';
            % app.CannyLamp.Color='r';
        end
        function applynetwork(app)
            if contains(lower(app.PleaseLoadModelLabel.Text), 'loaded')
                % Model is loaded, proceed with classification
                if contains(lower(app.PleaseLoadModelLabel.Text), 'alexnet')
                    app.original_img = imresize(app.original_img, [227, 227]);
                % elseif contains(lower(app.PleaseLoadModelLabel.Text), 'vgg16')
                else
                    app.original_img = imresize(app.original_img, [224, 224]);
                end
                % app.Prediction = classify(app.model, app.original_img);
                [app.Prediction, app.scores] = classify(app.model,app.original_img);
                app.PredictedLabel.Text = app.Prediction;
            
                % Hide all gestures initially
                app.onegesture.Visible = 'off';
                app.twogesture.Visible = 'off';
                app.threegesture.Visible = 'off';
                app.fourgesture.Visible = 'off';
                app.fivegesture.Visible = 'off';
                app.rockgesture.Visible='off';
                app.thumbgesture.Visible='off';
            
                % Show the appropriate gesture based on the prediction value
                switch app.PredictedLabel.Text
                    case 'one'
                        app.onegesture.Visible = 'on';
                    case 'two'
                        app.twogesture.Visible = 'on';
                    case 'three'
                        app.threegesture.Visible = 'on';
                    case 'four'
                        app.fourgesture.Visible = 'on';
                    case 'five'
                        app.fivegesture.Visible = 'on';
                    case 'rock'
                        app.rockgesture.Visible='on'
                    case 'thumbsup'
                        app.thumbgesture.Visible='on'
                    % For 'thumbsup' and 'rock', there is no need to change visibility as per your code
                end
                %classNames = MyAlexNet.Layers(end).ClassNames;
                classNames=app.model.Layers(end).ClassNames;
                % Display the confidence scores on another axes
                bar(app.ConfidenceAxis, app.scores); % Assuming app.ConfidenceAxes is the handle to your axes
                title(app.ConfidenceAxis, 'Confidence Scores');
                xlabel(app.ConfidenceAxis, 'Class');
                ylabel(app.ConfidenceAxis, 'Confidence Score');
                app.ConfidenceAxis.XColor=[1 1 1];
                app.ConfidenceAxis.YColor=[1 1 1];
            
                xtickangle(app.ConfidenceAxis, 45);
                xticklabels(app.ConfidenceAxis,classNames);
                ylim(app.ConfidenceAxis, [0, 1]);
                grid(app.ConfidenceAxis, 'on');
            else
                % Model is not loaded, change PredictedLabel to 'please load a model'
                app.PredictedLabel.Visible='on'
                app.PredictedLabel.Text = 'please load a model';
            end
        end

        function showmathsWebcam(app)
            app.MathsImage.ImageSource=snapshot(app.cam);
        end

        
        function resetMathsTab(app)
            if strcmp(get(app.mathsTimer,'Running'),'on')
                    stop(app.mathsTimer)
            end
            app.MathsImage.ImageSource='notyetloaded.png';
            app.SelectModelDropDown_2.Value='None';
            app.PleaseLoadModelLabel_2.Text='Please Load Model';
            app.TakeSnapshotButton.Enable='off';
            app.PreviousMathsLabel.Text='';
            app.CurrentMathsLabel.Text='';
            app.DidyoumeanLabel.Visible='off';
            app.DidyoumeanLabel.Visible='off';
            app.numGuessLabel.Visible='off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            app.ChooseOperator.Visible='off';
            app.ClickEnterLabel.Visible='off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axisreset(app)
            % app.onegesture.Visible='off';
            % app.twogesture.Visible='off';
            % app.threegesture.Visible='off';
            % app.fourgesture.Visible='off';
            % app.fivegesture.Visible='off';
            % app.rockgesture.Visible='off';
            % app.thumbgesture.Visible='off'
            % app.PredictedLabel.Visible='off';
            % app.CLOSECAMButton.Visible='off'

            %setup maths timer
            %set up timer for webcam
            app.mathsTimer = timer('Period',0.1, 'ExecutionMode','FixedSpacing', 'TasksToExecute', inf);
            app.mathsTimer.TimerFcn = @(x,y)showmathsWebcam(app);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
              [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct','*.WEBP'}, 'Select File to Open');
            fullname = [filepath, filename];
            app.original_img = imread(fullname);
            imshow(app.original_img,'Parent',app.UIAxes);
        end

        % Value changed function: SelectModelDropDown
        function SelectModelDropDownValueChanged(app, event)
            value = app.SelectModelDropDown.Value; %get rid of this line?
            app.PleaseLoadModelLabel.Text='Loading';
            pause(0.1) %line added by zak
            switch app.SelectModelDropDown.Value
                case 'None'
                    axisreset(app)
                case 'AlexNet'
                    load NewNet3.mat;
                    app.model=NewNet3;
                    app.PleaseLoadModelLabel.Text='AlexNet Loaded';
                case 'ShuffleNet'
                    load zakNet3.mat;
                    app.model=zakNet3;
                    app.PleaseLoadModelLabel.Text='ShuffleNet Loaded';    
                case 'ResNet'
                    load myResNet.mat;
                    app.model=trainedNetwork_2;
                    app.PleaseLoadModelLabel.Text='ResNet Loaded';         
            end
            
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
% app.M
%             app.original_img=imresize(app.original_img,[227,227]);
%             app.Prediction=classify(app.model,app.original_img);
%             app.PredictedLabel.Text=app.Prediction
%             % Hide all gestures initially
%             app.onegesture.Visible = 'off';
%             app.twogesture.Visible = 'off';
%             app.threegesture.Visible = 'off';
%             app.fourgesture.Visible = 'off';
%             app.fivegesture.Visible = 'off';
% 
%             % Show the appropriate gesture based on the prediction value
%             switch app.PredictedLabel.Text
%                 case 'one'
%                     app.onegesture.Visible = 'on';
%                 case 'two'
%                     app.twogesture.Visible = 'on';
%                 case 'three'
%                     app.threegesture.Visible = 'on';
%                 case 'four'
%                     app.fourgesture.Visible = 'on';
%                 case 'five'
%                     app.fivegesture.Visible = 'on';
%                 % For 'thumbsup' and 'rock', there is no need to change visibility as per your code
%             end
    % Check if the model is loaded
% Convert both strings to lowercase before comparison
if contains(lower(app.PleaseLoadModelLabel.Text), 'loaded')
    % Model is loaded, proceed with classification
    if contains(lower(app.PleaseLoadModelLabel.Text), 'alexnet')
        app.original_img = imresize(app.original_img, [227, 227]);
    % elseif contains(lower(app.PleaseLoadModelLabel.Text), 'vgg16')
    else
        app.original_img = imresize(app.original_img, [224, 224]);
    end
    % app.Prediction = classify(app.model, app.original_img);
    [app.Prediction, app.scores] = classify(app.model,app.original_img);
    app.PredictedLabel.Text = app.Prediction;

    % Hide all gestures initially
    app.onegesture.Visible = 'off';
    app.twogesture.Visible = 'off';
    app.threegesture.Visible = 'off';
    app.fourgesture.Visible = 'off';
    app.fivegesture.Visible = 'off';
    app.rockgesture.Visible='off';
    app.thumbgesture.Visible='off';

    % Show the appropriate gesture based on the prediction value
    switch lower(app.PredictedLabel.Text)
        case 'one'
            app.onegesture.Visible = 'on';
        case 'two'
            app.twogesture.Visible = 'on';
        case 'three'
            app.threegesture.Visible = 'on';
        case 'four'
            app.fourgesture.Visible = 'on';
        case 'five'
            app.fivegesture.Visible = 'on';
        case 'rock'
            app.rockgesture.Visible='on';
        case 'thumbsup'
            app.thumbgesture.Visible='on';
        % For 'thumbsup' and 'rock', there is no need to change visibility as per your code
        
    end
    %classNames = MyAlexNet.Layers(end).ClassNames;
    classNames=app.model.Layers(end).ClassNames;
    % Display the confidence scores on another axes
    bar(app.ConfidenceAxis, app.scores); % Assuming app.ConfidenceAxes is the handle to your axes
    title(app.ConfidenceAxis, 'Confidence Scores');
    xlabel(app.ConfidenceAxis, 'Class');
    ylabel(app.ConfidenceAxis, 'Confidence Score');
    app.ConfidenceAxis.XColor=[1 1 1];
    app.ConfidenceAxis.YColor=[1 1 1];

    xtickangle(app.ConfidenceAxis, 45);
    xticklabels(app.ConfidenceAxis,classNames);
    ylim(app.ConfidenceAxis, [0, 1]);
    grid(app.ConfidenceAxis, 'on');
else
    % Model is not loaded, change PredictedLabel to 'please load a model'
    app.PredictedLabel.Visible='on'
    app.PredictedLabel.Text = 'please load a model';
end


        end

        % Button pushed function: OpenCamButton
        function OpenCamButtonPushed(app, event)
cam=webcam(1);
app.CLOSECAMButton.Visible='on'
app.CONTINUOUSAPPLYButton.Visible='on'
app.Webcam_Status=true
while app.Webcam_Status
    frame=snapshot(cam);
    imagesc(frame,'Parent',app.UIAxes)
    app.original_img=frame;
    drawnow;
    if app.constantapply
        applynetwork(app)
    else
    end
end
        end

        % Value changed function: ImageOptionsDropDown
        function ImageOptionsDropDownValueChanged(app, event)
            value = app.ImageOptionsDropDown.Value;
          switch app.ImageOptionsDropDown.Value
            case 'None'
                axisreset(app)
                % filtersreset(app)
                % app.ImageOptionLabel.Text='No Options Selected'
            case '1'
               % app.original_img=imread('Augmented_HandImages\one\image_001.png');
               app.original_img=imread('1test.jpg');
                imshow(app.original_img,'Parent',app.UIAxes);
                app.NoAugmentationsButton.Value = true; 
            case '2'
               % app.original_img=imread('Augmented_HandImages\two\Alvin2_3.png');
               app.original_img=imread('2test.jpg');
                imshow(app.original_img,'Parent',app.UIAxes)
                app.NoAugmentationsButton.Value = true; 
            case '3'
             %   app.original_img=imread('Augmented_HandImages\three\flipped_image_011.png');
             app.original_img=imread('3test.jpg');
               imshow(app.original_img,'Parent',app.UIAxes);
               app.NoAugmentationsButton.Value = true; 
            case '4'
             %   app.original_img=imread('Augmented_HandImages\four\foury (2).png');
             app.original_img=imread('4test.jpg');
                imshow(app.original_img,'Parent',app.UIAxes)
                app.NoAugmentationsButton.Value = true; 
            case '5'
              %  app.original_img=imread('Augmented_HandImages\five\20240129_115059_073_saved.png')
              app.original_img=imread('5test.jpg');
                imshow(app.original_img,'Parent',app.UIAxes);
                app.NoAugmentationsButton.Value = true; 
            case 'Rock and Roll'
              %  app.original_img=imread('Augmented_HandImages\rock\flipped_20240129_115415_054_saved.png')
              app.original_img=imread('rocktest.jpg');
                imshow(app.original_img,'Parent',app.UIAxes);
                app.NoAugmentationsButton.Value = true; 
            case 'Thumbs Up'
               % app.original_img=imread('Augmented_HandImages\thumbsup\flipped_saqthumb (11).png')
               app.original_img=imread('thumbsuptest.png');
                imshow(app.original_img,'Parent',app.UIAxes);
                app.NoAugmentationsButton.Value = true; 
        end
        end

        % Button pushed function: CLOSECAMButton
        function CLOSECAMButtonPushed(app, event)
            app.Webcam_Status=false
            axisreset(app)
        end

        % Button down function: DataAugmentationsNOTWEBCAMButtonGroup
        function DataAugmentationsNOTWEBCAMButtonGroupButtonDown(app, event)
            % selectedButton=app.DataAugmentationsNOTWEBCAMButtonGroup.SelectedObject;
            % 
            % switch selectedButton.Tag
            %     case 'No Augmentations'
            %     case 'Rotate'
            %          rotationAngle = randi([-50, 50]); % Random angle between -50 and 50 degrees
            %          app.original_img.Image = imrotate(app.original_img.Image, rotationAngle); % Rotate by a random angle
            %          imshow(app.original_img,'Parent',app.UIAxes)
            % 
            %     case 'Noise Injection'
            %         noiseMean = 0; % Mean of Gaussian noise
            %         noiseVariance = rand(1) * (0.05 - 0.01) + 0.01; % Random variance between 0.01 and 0.05
            %         app.original_img.Image = imnoise(app.original_img.Image, 'gaussian', noiseMean, noiseVariance); % Add Gaussian noise
            %         imshow(app.original_img,'Parent',app.UIAxes)
            % end
        end

        % Selection changed function: DataAugmentationsNOTWEBCAMButtonGroup
        function DataAugmentationsNOTWEBCAMButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataAugmentationsNOTWEBCAMButtonGroup.SelectedObject;
      
            selectedButton = event.NewValue;

    switch selectedButton.Text
        case 'No Augmentations'
            % Do nothing
        case 'Rotate'
            rotationAngle = randi([-50, 50]); % Random angle between -50 and 50 degrees
            app.original_img = imrotate(app.original_img, rotationAngle); % Rotate by a random angle
            imshow(app.original_img, 'Parent', app.UIAxes)

        case 'Noise Injection'
            noiseMean = 0; % Mean of Gaussian noise
            noiseVariance = rand(1) * (0.05 - 0.01) + 0.01; % Random variance between 0.01 and 0.05
            app.original_img = imnoise(app.original_img, 'gaussian', noiseMean, noiseVariance); % Add Gaussian noise
            imshow(app.original_img, 'Parent', app.UIAxes)
    end


        end

        % Callback function
        function DataAugmentorButtonGroupButtonDown(app, event)
             selectedButton = app.DataAugmentationsNOTWEBCAMButtonGroup.SelectedObject;

   
        end

        % Callback function
        function DataAugmentorButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataAugmentorButtonGroup.SelectedObject;
               % Check which button is selected and perform actions accordingly
        switch selectedButton.Text
            case 'No Changes'
                % Do nothing
            case 'Rotate'
                % Perform rotating
                rotationAngle = randi([-50, 50]); % Random angle between -50 and 50 degrees
                app.original_img.Image = imrotate(app.original_img.Image, rotationAngle); % Rotate by a random angle
                imshow(app.original_img, 'Parent', app.UIAxes)

            case 'Inject Noise'
                % Inject more noise (e.g., Gaussian noise)
                noiseMean = 0; % Mean of Gaussian noise
                noiseVariance = rand(1) * (0.05 - 0.01) + 0.01; % Random variance between 0.01 and 0.05
                app.original_img.Image = imnoise(app.original_img.Image, 'gaussian', noiseMean, noiseVariance); % Add Gaussian noise
        end
              
        end

        % Callback function
        function ListBoxValueChanged(app, event)
value = app.ListBox.Value;
    switch value
        case 'No Augmentations'
            % Do nothing
        case 'Rotate'
            rotationAngle = randi([-50, 50]); % Random angle between -50 and 50 degrees
            app.original_img= imrotate(app.original_img, rotationAngle); % Rotate by a random angle
            imshow(app.original_img, 'Parent', app.UIAxes)

        case 'Noise Injection'
            noiseMean = 0; % Mean of Gaussian noise
            noiseVariance = rand(1) * (0.05 - 0.01) + 0.01; % Random variance between 0.01 and 0.05
            app.original_img = imnoise(app.original_img, 'gaussian', noiseMean, noiseVariance); % Add Gaussian noise
            imshow(app.original_img, 'Parent', app.UIAxes)
    end

        end

        % Callback function
        function ContinuousSwitchValueChanged(app, event)
            value = app.ContinuousSwitch.Value;
            app.switchstatus=value;
            switch value
                case'On'
                    applynetwork(app)
                case 'Off'
            end
        end

        % Button pushed function: CONTINUOUSAPPLYButton
        function CONTINUOUSAPPLYButtonPushed(app, event)
            app.constantapply=~app.constantapply;
        end

        % Value changed function: SelectModelDropDown_2
        function SelectModelDropDown_2ValueChanged(app, event)
            app.PleaseLoadModelLabel_2.Text='Loading...';
            app.MathsImage.ImageSource='loading.png';
            pause(0.1)
            ready=1;
            switch app.SelectModelDropDown_2.Value
                %reset image
                case 'AlexNet'
                    load NewNet3.mat;
                    app.model=NewNet3;
                    app.PleaseLoadModelLabel_2.Text='AlexNet Loaded';
                case 'ShuffleNet'
                    load zakNet3.mat;
                    app.model=zakNet3;
                    app.PleaseLoadModelLabel_2.Text='ShuffleNet Loaded';    
                case 'ResNet'
                    load myResNet.mat;
                    app.model=trainedNetwork_2;
                    app.PleaseLoadModelLabel_2.Text='ResNet Loaded';
                otherwise
                    app.PleaseLoadModelLabel_2.Text='Please Load Model';
                    app.MathsImage.ImageSource='notyetloaded.png';
                    ready=0;
            end
            
            %show live cam for maths
            if ready==1
                app.TakeSnapshotButton.Enable='on';
                if strcmp(get(app.mathsTimer,'Running'),'off')
                    start(app.mathsTimer) %using if statement avoids starting a timer that is already running
                end
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.axisreset()
            app.resetMathsTab()
        end

        % Button pushed function: TakeSnapshotButton
        function TakeSnapshotButtonPushed(app, event)
            app.TakeSnapshotButton.Enable='off';
            stop(app.mathsTimer);
            % image=app.MathsImage.ImageSource;
            if contains(lower(app.PleaseLoadModelLabel_2.Text), 'alexnet')
                image = imresize(app.MathsImage.ImageSource, [227, 227]);
            else
                image = imresize(app.MathsImage.ImageSource, [224, 224]);
            end
            
            [num,confidence]=classify(app.model,image)
            if max(confidence)<0.6
                %show that we couldnt get it
                %'push' no button
            end

            switch lower(string(num))
                case 'one'
                    app.newNum=1;
                case 'two'
                    app.newNum=2;
                case 'three'
                    app.newNum=3;
                case 'four'
                    app.newNum=4;
                case 'five'
                    app.newNum=5;
                otherwise
                    %show that we couldn't get it
                    %'push' no button
                    app.newNum=0;
            end
            app.numGuessLabel.Text=num2str(app.newNum);
            app.numGuessLabel.Visible='on';
            app.DidyoumeanLabel.Visible='on';
            app.YesButton.Visible='on';
            app.NoButton.Visible='on';
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.numGuessLabel.Visible='off';
            app.DidyoumeanLabel.Visible='off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            app.CurrentMathsLabel.Text=[app.CurrentMathsLabel.Text num2str(app.newNum) ' '];
            
            app.ChooseOperator.Visible='on'; %show the option to do another operation
            if length(app.CurrentMathsLabel.Text)~=2
                app.ClickEnterLabel.Visible='on'; %if only one number has been chosen then you cant click enter yet
            end            
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            start(app.mathsTimer)
            app.numGuessLabel.Visible='off';
            app.DidyoumeanLabel.Visible='off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            app.TakeSnapshotButton.Enable='on';
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            if strcmp(app.ChooseOperator.Visible,'on')
                app.ClickEnterLabel.Visible='off';
                app.CurrentMathsLabel.Text=[app.CurrentMathsLabel.Text '+ '];
                %remove chooseoperator label
                app.ChooseOperator.Visible='off';
                %show live cam for maths
                if strcmp(get(app.mathsTimer,'Running'),'off')
                    start(app.mathsTimer) %using if statement avoids starting a timer that is already running
                end
                app.TakeSnapshotButton.Enable='on';
            end
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            if strcmp(app.ChooseOperator.Visible,'on')
                app.ClickEnterLabel.Visible='off';
                app.CurrentMathsLabel.Text=[app.CurrentMathsLabel.Text '- '];
                %remove chooseoperator label
                app.ChooseOperator.Visible='off';
                %show live cam for maths
                if strcmp(get(app.mathsTimer,'Running'),'off')
                    start(app.mathsTimer) %using if statement avoids starting a timer that is already running
                end
                app.TakeSnapshotButton.Enable='on';
            end
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            if strcmp(app.ChooseOperator.Visible,'on')
                app.ClickEnterLabel.Visible='off';
                app.CurrentMathsLabel.Text=[app.CurrentMathsLabel.Text '* '];
                %remove chooseoperator label
                app.ChooseOperator.Visible='off';
                %show live cam for maths
                if strcmp(get(app.mathsTimer,'Running'),'off')
                    start(app.mathsTimer) %using if statement avoids starting a timer that is already running
                end
                app.TakeSnapshotButton.Enable='on';
            end
        end

        % Button pushed function: DivideButton
        function DivideButtonPushed(app, event)
            if strcmp(app.ChooseOperator.Visible,'on')
                app.ClickEnterLabel.Visible='off';
                app.CurrentMathsLabel.Text=[app.CurrentMathsLabel.Text '/ '];
                %remove chooseoperator label
                app.ChooseOperator.Visible='off';
                %show live cam for maths
                if strcmp(get(app.mathsTimer,'Running'),'off')
                    start(app.mathsTimer) %using if statement avoids starting a timer that is already running
                end
                app.TakeSnapshotButton.Enable='on';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if strcmp(app.ClickEnterLabel.Visible,'on')
                app.ClickEnterLabel.Visible='off';
                app.PreviousMathsLabel.Text=app.CurrentMathsLabel.Text;
                %do the maths
                app.CurrentMathsLabel.Text=[num2str(eval(app.PreviousMathsLabel.Text)) ' '];
                app.ChooseOperator.Visible='on';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
           app.resetMathsTab()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SandboxModeTab = uitab(app.TabGroup);
            app.SandboxModeTab.Title = 'Sandbox Mode';
            app.UIAxes = uiaxes(app.SandboxModeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConfidenceAxis = uiaxes(app.SandboxModeTab);
            title(app.ConfidenceAxis, 'Confidence Scores')
            xlabel(app.ConfidenceAxis, 'X')
            ylabel(app.ConfidenceAxis, 'Y')
            zlabel(app.ConfidenceAxis, 'Z')
            app.GestureRecognitionLabel = uilabel(app.SandboxModeTab);
            app.GestureRecognitionLabel.Text = 'Gesture Recognition';
            app.SandboxModeLabel = uilabel(app.SandboxModeTab);
            app.SandboxModeLabel.Text = 'Sandbox Mode';
            app.MakeyourpickPanel = uipanel(app.SandboxModeTab);
            app.MakeyourpickPanel.Title = 'Make your pick!';
            app.ImageOptionsDropDownLabel = uilabel(app.MakeyourpickPanel);
            app.ImageOptionsDropDownLabel.Text = 'Image Options';
            app.ImageOptionsDropDown = uidropdown(app.MakeyourpickPanel);
            app.ImageOptionsDropDown.Items = {'None', '1', '2', '3', '4', '5', 'Thumbs Up', 'Rock and Roll'};
            app.ImageOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageOptionsDropDownValueChanged, true);
            app.ImageOptionsDropDown.Value = 'None';
            app.SelectModelDropDownLabel = uilabel(app.MakeyourpickPanel);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.MakeyourpickPanel);
            app.SelectModelDropDown.Items = {'None', 'AlexNet', 'ShuffleNet', 'ResNet'};
            app.SelectModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModelDropDownValueChanged, true);
            app.SelectModelDropDown.Value = 'None';
            app.LoadButton = uibutton(app.MakeyourpickPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CustomImageLabel = uilabel(app.MakeyourpickPanel);
            app.CustomImageLabel.Text = 'Custom Image';
            app.OpenCamButton = uibutton(app.MakeyourpickPanel, 'push');
            app.OpenCamButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCamButtonPushed, true);
            app.OpenCamButton.Text = 'Open Cam';
            app.WebcamInputLabel = uilabel(app.MakeyourpickPanel);
            app.WebcamInputLabel.Text = 'Webcam Input';
            app.ClassifyButton = uibutton(app.SandboxModeTab, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.PredictedLabel = uilabel(app.SandboxModeTab);
            app.PredictedLabel.Text = 'Predicted Label';
            app.PleaseLoadModelLabel = uilabel(app.SandboxModeTab);
            app.PleaseLoadModelLabel.Text = 'Please Load Model';
            app.twogesture = uiimage(app.SandboxModeTab);
            app.twogesture.ImageSource = fullfile(pathToMLAPP, '2.png');
            app.onegesture = uiimage(app.SandboxModeTab);
            app.onegesture.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.threegesture = uiimage(app.SandboxModeTab);
            app.threegesture.ImageSource = fullfile(pathToMLAPP, '3-removebg-preview.png');
            app.fourgesture = uiimage(app.SandboxModeTab);
            app.fourgesture.ImageSource = fullfile(pathToMLAPP, '4-removebg-preview.png');
            app.fivegesture = uiimage(app.SandboxModeTab);
            app.fivegesture.ImageSource = fullfile(pathToMLAPP, '5-removebg-preview.png');
            app.ModelStatusLabel = uilabel(app.SandboxModeTab);
            app.ModelStatusLabel.Text = 'Model Status:';
            app.CLOSECAMButton = uibutton(app.SandboxModeTab, 'push');
            app.CLOSECAMButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSECAMButtonPushed, true);
            app.CLOSECAMButton.Text = 'CLOSE CAM';
            app.rockgesture = uiimage(app.SandboxModeTab);
            app.rockgesture.ImageSource = fullfile(pathToMLAPP, 'rockpic.png');
            app.thumbgesture = uiimage(app.SandboxModeTab);
            app.thumbgesture.ImageSource = fullfile(pathToMLAPP, 'thumb.png');
            app.DataAugmentationsNOTWEBCAMButtonGroup = uibuttongroup(app.SandboxModeTab);
            app.DataAugmentationsNOTWEBCAMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataAugmentationsNOTWEBCAMButtonGroupSelectionChanged, true);
            app.DataAugmentationsNOTWEBCAMButtonGroup.Title = 'Data Augmentations - NOT WEBCAM';
            app.DataAugmentationsNOTWEBCAMButtonGroup.ButtonDownFcn = createCallbackFcn(app, @DataAugmentationsNOTWEBCAMButtonGroupButtonDown, true);
            app.NoAugmentationsButton = uiradiobutton(app.DataAugmentationsNOTWEBCAMButtonGroup);
            app.NoAugmentationsButton.Text = 'No Augmentations';
            app.NoAugmentationsButton.Value = true;
            app.RotateButton = uiradiobutton(app.DataAugmentationsNOTWEBCAMButtonGroup);
            app.RotateButton.Text = 'Rotate';
            app.NoiseInjectionButton = uiradiobutton(app.DataAugmentationsNOTWEBCAMButtonGroup);
            app.NoiseInjectionButton.Text = 'Noise Injection';
            app.CONTINUOUSAPPLYButton = uibutton(app.SandboxModeTab, 'push');
            app.CONTINUOUSAPPLYButton.ButtonPushedFcn = createCallbackFcn(app, @CONTINUOUSAPPLYButtonPushed, true);
            app.CONTINUOUSAPPLYButton.Text = 'CONTINUOUS APPLY';
            app.MathematicsGameTab = uitab(app.TabGroup);
            app.MathematicsGameTab.Title = 'Mathematics Game';
            app.GestureRecognitionLabel_2 = uilabel(app.MathematicsGameTab);
            app.GestureRecognitionLabel_2.Text = 'Gesture Recognition';
            app.MathsGameModeLabel = uilabel(app.MathematicsGameTab);
            app.MathsGameModeLabel.Text = 'Maths Game Mode';
            app.PleaseLoadModelLabel_2 = uilabel(app.MathematicsGameTab);
            app.PleaseLoadModelLabel_2.Text = 'Please Load Model';
            app.ModelStatusLabel_2 = uilabel(app.MathematicsGameTab);
            app.ModelStatusLabel_2.Text = 'Model Status:';
            app.MathsImage = uiimage(app.MathematicsGameTab);
            app.MathsImage.ImageSource = fullfile(pathToMLAPP, 'notyetloaded.png');
            app.MakeyourpickPanel_2 = uipanel(app.MathematicsGameTab);
            app.MakeyourpickPanel_2.Title = 'Make your pick!';
            app.SelectModelDropDown_2Label = uilabel(app.MakeyourpickPanel_2);
            app.SelectModelDropDown_2Label.Text = 'Select Model';
            app.SelectModelDropDown_2 = uidropdown(app.MakeyourpickPanel_2);
            app.SelectModelDropDown_2.Items = {'None', 'AlexNet', 'ShuffleNet', 'ResNet'};
            app.SelectModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectModelDropDown_2ValueChanged, true);
            app.SelectModelDropDown_2.Value = 'None';
            app.TakeSnapshotButton = uibutton(app.MathematicsGameTab, 'push');
            app.TakeSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapshotButtonPushed, true);
            app.TakeSnapshotButton.Enable = 'off';
            app.TakeSnapshotButton.Text = 'Take Snapshot!';
            app.CalculatorPanel = uipanel(app.MathematicsGameTab);
            app.CalculatorPanel.Title = 'Calculator';
            app.PlusButton = uibutton(app.CalculatorPanel, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.CalculatorPanel, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.MultiplyButton = uibutton(app.CalculatorPanel, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'x';
            app.DivideButton = uibutton(app.CalculatorPanel, 'push');
            app.DivideButton.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.DivideButton.Text = '/';
            app.ClearButton = uibutton(app.CalculatorPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CurrentMathsLabel = uilabel(app.CalculatorPanel);
            app.CurrentMathsLabel.Text = '';
            app.PreviousMathsLabel = uilabel(app.CalculatorPanel);
            app.PreviousMathsLabel.Text = '';
            app.PreviousCalculationLabel = uilabel(app.CalculatorPanel);
            app.PreviousCalculationLabel.Text = ' Previous Calculation:';
            app.EnterButton = uibutton(app.CalculatorPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.CurrentCalculationLabel = uilabel(app.CalculatorPanel);
            app.CurrentCalculationLabel.Text = ' Current Calculation';
            app.DidyoumeanLabel = uilabel(app.MathematicsGameTab);
            app.DidyoumeanLabel.Visible = 'off';
            app.DidyoumeanLabel.Text = 'Did you mean:                          ?';
            app.numGuessLabel = uilabel(app.MathematicsGameTab);
            app.numGuessLabel.Visible = 'off';
            app.numGuessLabel.Text = '';
            app.YesButton = uibutton(app.MathematicsGameTab, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Visible = 'off';
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.MathematicsGameTab, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Visible = 'off';
            app.NoButton.Text = 'No';
            app.ChooseOperator = uilabel(app.MathematicsGameTab);
            app.ChooseOperator.Visible = 'off';
            app.ChooseOperator.Text = 'Choose a Mathematical Operator on the Calculator';
            app.ClickEnterLabel = uilabel(app.MathematicsGameTab);
            app.ClickEnterLabel.Visible = 'off';
            app.ClickEnterLabel.Text = 'Or Click Enter to find the answer!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gesture_Recognition_App_WithMaths

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end